classdef Comparison_Mode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComparisonModeUIFigure        matlab.ui.Figure
        UIAxesS1F                     matlab.ui.control.UIAxes
        UIAxesS1T                     matlab.ui.control.UIAxes
        BrowseButton                  matlab.ui.control.Button
        AudioFileEditFieldLabel       matlab.ui.control.Label
        AudioFileEditField            matlab.ui.control.EditField
        LengthSliderLabel             matlab.ui.control.Label
        LengthSlider                  matlab.ui.control.Slider
        StartSliderLabel              matlab.ui.control.Label
        StartSlider                   matlab.ui.control.Slider
        LengthFineTuneSpinnerLabel    matlab.ui.control.Label
        LengthFineTuneSpinner         matlab.ui.control.Spinner
        StartFineTuneSpinnerLabel     matlab.ui.control.Label
        StartFineTuneSpinner          matlab.ui.control.Spinner
        NDFTKnobLabel                 matlab.ui.control.Label
        NDFTKnob                      matlab.ui.control.DiscreteKnob
        WindowChoiceDropDownLabel     matlab.ui.control.Label
        WindowChoiceDropDown          matlab.ui.control.DropDown
        RBWEditFieldLabel             matlab.ui.control.Label
        RBWEditField                  matlab.ui.control.NumericEditField
        WindowChoiceDropDown_2Label   matlab.ui.control.Label
        WindowChoiceDropDown_2        matlab.ui.control.DropDown
        LengthSlider_2Label           matlab.ui.control.Label
        LengthSlider_2                matlab.ui.control.Slider
        LengthFineTuneSpinner_2Label  matlab.ui.control.Label
        LengthFineTuneSpinner_2       matlab.ui.control.Spinner
        NDFTKnob_2Label               matlab.ui.control.Label
        NDFTKnob_2                    matlab.ui.control.DiscreteKnob
        RBWEditField_2Label           matlab.ui.control.Label
        RBWEditField_2                matlab.ui.control.NumericEditField
        UIAxesS2F                     matlab.ui.control.UIAxes
        UIAxesS2T                     matlab.ui.control.UIAxes
        BackButton                    matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        MainMenuButton                matlab.ui.control.Button
    end


    properties (Access = private)
        Input_Signal % the audio file loaded.
        F_s % its sampling frequency.
    end

    methods (Access = private)
    
        function Plot_All(app)
            if app.F_s~=-1
               app.RBWEditField.Value=app.F_s/str2double(app.NDFTKnob.Value);
               [w1, w2] = app.Get_Window;
               w1=w1';
               w2=w2'; % to match the orientation of the input signal.
               start= app.StartFineTuneSpinner.Value;
               signal1= w1.*app.Input_Signal(start+1:start+length(w1));
               t_axis1=(start:start+length(w1)-1)/app.F_s;
               plot(app.UIAxesS1T,t_axis1,signal1)
               freq_signal1=fft_rec(app,[signal1 zeros(1,str2double(app.NDFTKnob.Value)-length(w1))]);
               f_axis1=(0:length(freq_signal1)-1)*app.F_s/str2double(app.NDFTKnob.Value);
               plot(app.UIAxesS1F,f_axis1,abs(freq_signal1))
               %% Second function
               app.RBWEditField_2.Value=app.F_s/str2double(app.NDFTKnob_2.Value);
               signal2= w2.*app.Input_Signal(start+1:start+length(w2));
               t_axis2=(start:start+length(w2)-1)/app.F_s;
               plot(app.UIAxesS2T,t_axis2,signal2)
               freq_signal2=fft_rec(app,[signal2 zeros(1,str2double(app.NDFTKnob_2.Value)-length(w2))]);
               f_axis2=(0:length(freq_signal2)-1)*app.F_s/str2double(app.NDFTKnob_2.Value);
               plot(app.UIAxesS2F,f_axis2,abs(freq_signal2))
            end
            
        end
        
        function [w1, w2] = Get_Window(app)
            switch app.WindowChoiceDropDown.Value
                case 'Rectangular'
                    w1=rectwin(app.LengthFineTuneSpinner.Value);
                case 'Triangular'
                    w1=triang(app.LengthFineTuneSpinner.Value);
                case 'Hamming'
                    w1=hamming(app.LengthFineTuneSpinner.Value,'periodic');
                case 'Hanning'
                    w1=hann(app.LengthFineTuneSpinner.Value,'periodic');
            end
            switch app.WindowChoiceDropDown_2.Value
                case 'Rectangular'
                    w2=rectwin(app.LengthFineTuneSpinner_2.Value);
                case 'Triangular'
                    w2=triang(app.LengthFineTuneSpinner_2.Value);
                case 'Hamming'
                    w2=hamming(app.LengthFineTuneSpinner_2.Value,'periodic');
                case 'Hanning'
                    w2=hann(app.LengthFineTuneSpinner_2.Value,'periodic');
            end
            return
        end
        
        function res = fft_rec(app,input_signal)
            N=length(input_signal);
            if N==1
                res=input_signal;
                return;
            end
            twiddles=exp(-2i*pi*(0:N-1)./N);
            first_half=fft_rec(app,input_signal(1:2:N));
            second_half =fft_rec(app,input_signal(2:2:N));
            res = [ first_half+ twiddles(1:N/2).*second_half , first_half+ twiddles(N/2+1:N).*second_half];
        end 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.LengthFineTuneSpinner.Value=str2double(app.NDFTKnob.Value);
           app.LengthFineTuneSpinner.Limits=[10,app.LengthFineTuneSpinner.Value];
           app.LengthFineTuneSpinner_2.Value=str2double(app.NDFTKnob_2.Value);
           app.LengthFineTuneSpinner_2.Limits=[10,app.LengthFineTuneSpinner_2.Value];
           app.LengthSlider.Limits=app.LengthFineTuneSpinner.Limits;
           app.LengthSlider.Value=app.LengthFineTuneSpinner.Value;
           app.LengthSlider_2.Limits=app.LengthFineTuneSpinner_2.Limits;
           app.LengthSlider_2.Value=app.LengthFineTuneSpinner_2.Value;
           app.StartFineTuneSpinner.Enable=false;
           app.StartSlider.Enable=false;
           app.WindowChoiceDropDown.Enable=false;
           app.NDFTKnob.Enable=false;
           app.LengthSlider.Enable=false;
           app.LengthFineTuneSpinner.Enable=false;
           app.WindowChoiceDropDown_2.Enable=false;
           app.NDFTKnob_2.Enable=false;
           app.LengthSlider_2.Enable=false;
           app.LengthFineTuneSpinner_2.Enable=false;
           app.F_s=-1; %Initialize and indicate that there is no signal loaded.
           app.ComparisonModeUIFigure.WindowState='maximized'; % Start in Maximized Mode.
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path]= uigetfile({'*.wav;*.mp3;*.flac;*.ogg'},...
                'Select an Audio File');
            app.ComparisonModeUIFigure.Visible = 'off';
            app.ComparisonModeUIFigure.Visible = 'on';
            if file ~=0
                 app.AudioFileEditField.Value = file;
                  try
                     [app.Input_Signal , app.F_s]=audioread(strcat(path,file));
                     app.Input_Signal=app.Input_Signal';
                     app.StartFineTuneSpinner.Limits=[0,...
                         length(app.Input_Signal)-app.LengthFineTuneSpinner.Value-1];
                     app.StartSlider.Limits=app.StartFineTuneSpinner.Limits;
                     app.StartFineTuneSpinner.Enable=true;
                     app.StartSlider.Enable=true;
                     app.WindowChoiceDropDown.Enable=true;
                     app.NDFTKnob.Enable=true;
                     app.LengthSlider.Enable=true;
                     app.LengthFineTuneSpinner.Enable=true;
                     app.WindowChoiceDropDown_2.Enable=true;
                     app.NDFTKnob_2.Enable=true;
                     app.LengthSlider_2.Enable=true;
                     app.LengthFineTuneSpinner_2.Enable=true;
                     app.Plot_All;
                     
                 catch exp
                     switch exp.identifier
                         case 'MATLAB:audiovideo:audioread:FileTypeNotSupported'
                             message= sprintf('File type not supported. \n Please Enter a valid audio file!');
                             uialert(app.ComparisonModeUIFigure,...
                            message,...
                            "INVALID file!");
                         case 'MATLAB:nomem'
                             message= sprintf('Out of Memory \n Please enter a smaller file');
                              uialert(app.ComparisonModeUIFigure,...
                            message,"No Memory");
                     end
                 end  
            else
                app.AudioFileEditField.Value = "";
                uialert(app.ComparisonModeUIFigure,...
                    "Please Select a file!",'No file selected',...
                    'Icon','Warning');
            end

        end

        % Value changing function: StartSlider
        function StartSliderValueChanging(app, event)
            changingValue = event.Value;
            app.StartFineTuneSpinner.Value=changingValue;
            app.Plot_All;
        end

        % Value changing function: StartFineTuneSpinner
        function StartFineTuneSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.StartSlider.Value=changingValue;
            app.Plot_All;
        end

        % Value changing function: LengthSlider
        function LengthSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LengthFineTuneSpinner.Value=changingValue;
            app.Plot_All;
        end

        % Value changing function: LengthFineTuneSpinner
        function LengthFineTuneSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.LengthSlider.Value=changingValue;
            app.Plot_All;
        end

        % Value changing function: LengthSlider_2
        function LengthSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.LengthFineTuneSpinner_2.Value=changingValue;
            app.Plot_All;
        end

        % Value changing function: LengthFineTuneSpinner_2
        function LengthFineTuneSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.LengthSlider_2.Value=changingValue;
            app.Plot_All;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Spectral_Analyzer
            closereq
        end

        % Value changed function: NDFTKnob
        function NDFTKnobValueChanged(app, event)
            app.LengthFineTuneSpinner.Limits=[10,str2double(app.NDFTKnob.Value)];
            app.LengthFineTuneSpinner.Value=str2double(app.NDFTKnob.Value); % swapped these to avoid errors.
            app.LengthSlider.Limits=app.LengthFineTuneSpinner.Limits;
            app.LengthSlider.Value=app.LengthFineTuneSpinner.Value;
            app.StartFineTuneSpinner.Limits=[0,...
                         length(app.Input_Signal)-max(app.LengthFineTuneSpinner.Value,...
                         app.LengthFineTuneSpinner_2.Value)-1];
            app.StartSlider.Limits=app.StartFineTuneSpinner.Limits;
            app.Plot_All;
        end

        % Value changed function: NDFTKnob_2
        function NDFTKnob_2ValueChanged(app, event)
            app.LengthFineTuneSpinner_2.Limits=[10,str2double(app.NDFTKnob_2.Value)];
            app.LengthFineTuneSpinner_2.Value=str2double(app.NDFTKnob_2.Value); % swapped these to avoid errors.
            app.LengthSlider_2.Limits=app.LengthFineTuneSpinner_2.Limits;
            app.LengthSlider_2.Value=app.LengthFineTuneSpinner_2.Value;
            app.StartFineTuneSpinner.Limits=[0,...
                         length(app.Input_Signal)-max(app.LengthFineTuneSpinner.Value,...
                         app.LengthFineTuneSpinner_2.Value)-1];
            app.StartSlider.Limits=app.StartFineTuneSpinner.Limits;
            app.Plot_All;
        end

        % Value changed function: WindowChoiceDropDown
        function WindowChoiceDropDownValueChanged(app, event)
           app.Plot_All;
        end

        % Value changed function: WindowChoiceDropDown_2
        function WindowChoiceDropDown_2ValueChanged(app, event)
            app.Plot_All;
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            Main_Menu
            closereq
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComparisonModeUIFigure = uifigure;
            app.ComparisonModeUIFigure.Name = 'Comparison Mode';
            app.UIAxesS1F = uiaxes(app.ComparisonModeUIFigure);
            title(app.UIAxesS1F, 'Frequency-Domain Signal')
            xlabel(app.UIAxesS1F, 'f Hz')
            ylabel(app.UIAxesS1F, '|x(f)|')
            app.UIAxesS1T = uiaxes(app.ComparisonModeUIFigure);
            title(app.UIAxesS1T, 'Time-Domain Signal')
            xlabel(app.UIAxesS1T, 't s ')
            ylabel(app.UIAxesS1T, 'x(t)')
            app.BrowseButton = uibutton(app.ComparisonModeUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.AudioFileEditFieldLabel = uilabel(app.ComparisonModeUIFigure);
            app.AudioFileEditFieldLabel.Text = 'Audio File';
            app.AudioFileEditField = uieditfield(app.ComparisonModeUIFigure, 'text');
            app.LengthSliderLabel = uilabel(app.ComparisonModeUIFigure);
            app.LengthSliderLabel.Text = 'Length';
            app.LengthSlider = uislider(app.ComparisonModeUIFigure);
            app.LengthSlider.ValueChangingFcn = createCallbackFcn(app, @LengthSliderValueChanging, true);
            app.StartSliderLabel = uilabel(app.ComparisonModeUIFigure);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.ComparisonModeUIFigure);
            app.StartSlider.ValueChangingFcn = createCallbackFcn(app, @StartSliderValueChanging, true);
            app.LengthFineTuneSpinnerLabel = uilabel(app.ComparisonModeUIFigure);
            app.LengthFineTuneSpinnerLabel.Text = 'Length Fine Tune';
            app.LengthFineTuneSpinner = uispinner(app.ComparisonModeUIFigure);
            app.LengthFineTuneSpinner.ValueChangingFcn = createCallbackFcn(app, @LengthFineTuneSpinnerValueChanging, true);
            app.StartFineTuneSpinnerLabel = uilabel(app.ComparisonModeUIFigure);
            app.StartFineTuneSpinnerLabel.Text = 'Start Fine Tune';
            app.StartFineTuneSpinner = uispinner(app.ComparisonModeUIFigure);
            app.StartFineTuneSpinner.ValueChangingFcn = createCallbackFcn(app, @StartFineTuneSpinnerValueChanging, true);
            app.NDFTKnobLabel = uilabel(app.ComparisonModeUIFigure);
            app.NDFTKnobLabel.Text = 'N-DFT';
            app.NDFTKnob = uiknob(app.ComparisonModeUIFigure, 'discrete');
            app.NDFTKnob.Items = {'8192', '16384', '32768', '65536'};
            app.NDFTKnob.ValueChangedFcn = createCallbackFcn(app, @NDFTKnobValueChanged, true);
            app.NDFTKnob.Value = '65536';
            app.WindowChoiceDropDownLabel = uilabel(app.ComparisonModeUIFigure);
            app.WindowChoiceDropDownLabel.Text = 'Window Choice';
            app.WindowChoiceDropDown = uidropdown(app.ComparisonModeUIFigure);
            app.WindowChoiceDropDown.Items = {'Rectangular', 'Triangular', 'Hamming', 'Hanning'};
            app.WindowChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowChoiceDropDownValueChanged, true);
            app.WindowChoiceDropDown.Value = 'Rectangular';
            app.RBWEditFieldLabel = uilabel(app.ComparisonModeUIFigure);
            app.RBWEditFieldLabel.Text = 'RBW';
            app.RBWEditField = uieditfield(app.ComparisonModeUIFigure, 'numeric');
            app.RBWEditField.Editable = 'off';
            app.WindowChoiceDropDown_2Label = uilabel(app.ComparisonModeUIFigure);
            app.WindowChoiceDropDown_2Label.Text = 'Window Choice';
            app.WindowChoiceDropDown_2 = uidropdown(app.ComparisonModeUIFigure);
            app.WindowChoiceDropDown_2.Items = {'Rectangular', 'Triangular', 'Hamming', 'Hanning'};
            app.WindowChoiceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @WindowChoiceDropDown_2ValueChanged, true);
            app.WindowChoiceDropDown_2.Value = 'Rectangular';
            app.LengthSlider_2Label = uilabel(app.ComparisonModeUIFigure);
            app.LengthSlider_2Label.Text = 'Length';
            app.LengthSlider_2 = uislider(app.ComparisonModeUIFigure);
            app.LengthSlider_2.ValueChangingFcn = createCallbackFcn(app, @LengthSlider_2ValueChanging, true);
            app.LengthFineTuneSpinner_2Label = uilabel(app.ComparisonModeUIFigure);
            app.LengthFineTuneSpinner_2Label.Text = 'Length Fine Tune';
            app.LengthFineTuneSpinner_2 = uispinner(app.ComparisonModeUIFigure);
            app.LengthFineTuneSpinner_2.ValueChangingFcn = createCallbackFcn(app, @LengthFineTuneSpinner_2ValueChanging, true);
            app.NDFTKnob_2Label = uilabel(app.ComparisonModeUIFigure);
            app.NDFTKnob_2Label.Text = 'N-DFT';
            app.NDFTKnob_2 = uiknob(app.ComparisonModeUIFigure, 'discrete');
            app.NDFTKnob_2.Items = {'8192', '16384', '32768', '65536'};
            app.NDFTKnob_2.ValueChangedFcn = createCallbackFcn(app, @NDFTKnob_2ValueChanged, true);
            app.NDFTKnob_2.Value = '65536';
            app.RBWEditField_2Label = uilabel(app.ComparisonModeUIFigure);
            app.RBWEditField_2Label.Text = 'RBW';
            app.RBWEditField_2 = uieditfield(app.ComparisonModeUIFigure, 'numeric');
            app.RBWEditField_2.Editable = 'off';
            app.UIAxesS2F = uiaxes(app.ComparisonModeUIFigure);
            title(app.UIAxesS2F, 'Frequency-Domain Signal')
            xlabel(app.UIAxesS2F, 'f Hz')
            ylabel(app.UIAxesS2F, '|x(f)|')
            app.UIAxesS2T = uiaxes(app.ComparisonModeUIFigure);
            title(app.UIAxesS2T, 'Time-Domain Signal')
            xlabel(app.UIAxesS2T, 't s ')
            ylabel(app.UIAxesS2T, 'x(t)')
            app.BackButton = uibutton(app.ComparisonModeUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ExitButton = uibutton(app.ComparisonModeUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.MainMenuButton = uibutton(app.ComparisonModeUIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Comparison_Mode

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComparisonModeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComparisonModeUIFigure)
        end
    end
end