classdef Discrete_conv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiscreteConvolutionUIFigure    matlab.ui.Figure
        FirstFunctionDropDownLabel     matlab.ui.control.Label
        FirstFunctionDropDown          matlab.ui.control.DropDown
        SecondFunctionDropDownLabel    matlab.ui.control.Label
        SecondFunctionDropDown         matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        ExitButton                     matlab.ui.control.Button
        ConvolveButton                 matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
        AmplitudeSliderLabel           matlab.ui.control.Label
        AmplitudeSlider                matlab.ui.control.Slider
        WidthOddintegersSliderLabel    matlab.ui.control.Label
        WidthOddintegersSlider         matlab.ui.control.Slider
        AmplitudeSlider_2Label         matlab.ui.control.Label
        AmplitudeSlider_2              matlab.ui.control.Slider
        WidthOddintegersSlider_2Label  matlab.ui.control.Label
        WidthOddintegersSlider_2       matlab.ui.control.Slider
    end


    properties (Access = private)
        FFYarray; % First function Y axis Array
        FFXarray; % X axis
        SFYarray; % Second function Y axis
        SFXarray; % Second function X Axis
    end

    methods (Access = private)
        
        function plotSig(app,type,IsFirst)
            if IsFirst
                UI=app.UIAxes;
                W=floor(app.WidthOddintegersSlider.Value);
                Amp=app.AmplitudeSlider.Value;
            else
                UI=app.UIAxes2;
                W=floor(app.WidthOddintegersSlider_2.Value);
                Amp=app.AmplitudeSlider_2.Value;
            end
            x=-(floor(W*1)):(floor(W*1));
            switch type
                case 'Rect'
                    y= (x>=-(W-1)/2 & x<=(W-1)/2) +(x==ceil((W-1)/2) & rem(W,2)==0);
                    y=Amp*y;
                case 'Tri'
                    y= (abs(x)<(W-1)/2).*(Amp*ones(1,length(x))-Amp/floor((W-1)/2)*abs(x));
                case 'DTri'
                   y= (abs(x)<=(W-1)/2).*(Amp/floor((W-1)/2)*abs(x)); 
            end
                    
            stem(UI,x,y);
            axis(UI,[min(x),max(x),0,ceil(Amp)]);
            if IsFirst
                app.FFYarray=y;
                app.FFXarray=x;
            else
                app.SFYarray=y;
                app.SFXarray=x;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            FirstFunctionDropDownValueChanged(app, 0);
            SecondFunctionDropDownValueChanged(app, 0);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Main_Menu 
            closereq
        end

        % Value changed function: FirstFunctionDropDown
        function FirstFunctionDropDownValueChanged(app, event)
            value = app.FirstFunctionDropDown.Value;
            if value=="Rectangular Pulse"
                plotSig(app,'Rect',true)
            end
            if value=="Triangular Pulse"
                plotSig(app,'Tri',true)
            end
            if value=="Double Triangular Pulse"
                plotSig(app,'DTri',true)
            end
        end

        % Value changed function: SecondFunctionDropDown
        function SecondFunctionDropDownValueChanged(app, event)
            value = app.SecondFunctionDropDown.Value;
            if value=="Rectangular Pulse"
                plotSig(app,'Rect',false)
            end
            if value=="Triangular Pulse"
                plotSig(app,'Tri',false)
            end
            if value=="Double Triangular Pulse"
                plotSig(app,'DTri',false)
            end
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            FirstFunctionDropDownValueChanged(app, event)
        end

        % Value changed function: WidthOddintegersSlider
        function WidthOddintegersSliderValueChanged(app, event)
            FirstFunctionDropDownValueChanged(app, event)
        end

        % Value changed function: AmplitudeSlider_2
        function AmplitudeSlider_2ValueChanged(app, event)
            SecondFunctionDropDownValueChanged(app, event)
        end

        % Value changed function: WidthOddintegersSlider_2
        function WidthOddintegersSlider_2ValueChanged(app, event)
            SecondFunctionDropDownValueChanged(app, event)            
        end

        % Button pushed function: ConvolveButton
        function ConvolveButtonPushed(app, event)
            x=[zeros(1,abs(min(app.SFXarray))),...
                app.FFYarray, zeros(1,abs(max(app.SFXarray)))]; %x[n] in th convolution zero-padded
            h=[zeros(1,abs(min(app.FFXarray))),...
                    fliplr(app.SFYarray),zeros(1,abs(max(app.FFXarray)))]; %h[n] in the conv. will be flipped
            x_ans= min(app.FFXarray)+min(app.SFXarray):max(app.FFXarray)+max(app.SFXarray);
            y_n=zeros(1,length(x_ans));
            % the x axis of the answer.
            for i=1:length(x_ans)
                temp=circshift(h,x_ans(i));
                % if the shift is left add a binary masks with zeros on the right.
                if x_ans(i)<0
                    BinaryMask=[ones(1,length(h)-abs(x_ans(i))-1),...
                    zeros(1,abs(x_ans(i))+1)];
                else % not right shift with the zeros on the left. 
                    BinaryMask=[zeros(1,abs(x_ans(i))+1),...
                    ones(1,length(h)-abs(x_ans(i))-1)];
                end
                temp=temp.*BinaryMask; %Mult. by the BinaryMask to get rid of the residues from the circshift.
                cla(app.UIAxes3); % clears the figure from the last iteration 
                stem(app.UIAxes3,x_ans,x); % Plot the fixed function
                hold (app.UIAxes3, 'on'); % hold on to plot over
                stem(app.UIAxes3,x_ans,temp); 
                % Plot the sweeping function over it.
                y_n(i)=sum(temp.*x); % Accumilate the result and add it to the result array.
                stem(app.UIAxes4,x_ans,y_n); % Plot the result array.
                pause(0.2);                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiscreteConvolutionUIFigure = uifigure;
            app.DiscreteConvolutionUIFigure.Name = 'Discrete Convolution';
            app.FirstFunctionDropDownLabel = uilabel(app.DiscreteConvolutionUIFigure);
            app.FirstFunctionDropDownLabel.Text = 'First Function';
            app.FirstFunctionDropDown = uidropdown(app.DiscreteConvolutionUIFigure);
            app.FirstFunctionDropDown.Items = {'Rectangular Pulse', 'Triangular Pulse', 'Double Triangular Pulse'};
            app.FirstFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstFunctionDropDownValueChanged, true);
            app.FirstFunctionDropDown.Value = 'Rectangular Pulse';
            app.SecondFunctionDropDownLabel = uilabel(app.DiscreteConvolutionUIFigure);
            app.SecondFunctionDropDownLabel.Text = 'Second Function';
            app.SecondFunctionDropDown = uidropdown(app.DiscreteConvolutionUIFigure);
            app.SecondFunctionDropDown.Items = {'Rectangular Pulse', 'Triangular Pulse', 'Double Triangular Pulse'};
            app.SecondFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondFunctionDropDownValueChanged, true);
            app.SecondFunctionDropDown.Value = 'Rectangular Pulse';
            app.UIAxes = uiaxes(app.DiscreteConvolutionUIFigure);
            title(app.UIAxes, 'First Function')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'x[n]')
            app.UIAxes2 = uiaxes(app.DiscreteConvolutionUIFigure);
            title(app.UIAxes2, 'Second Function')
            xlabel(app.UIAxes2, 'n')
            ylabel(app.UIAxes2, 'x_2[n]')
            app.UIAxes3 = uiaxes(app.DiscreteConvolutionUIFigure);
            title(app.UIAxes3, 'Animated Steps')
            xlabel(app.UIAxes3, 'n')
            ylabel(app.UIAxes3, 'x[k]/x_2[n-k]')
            app.UIAxes4 = uiaxes(app.DiscreteConvolutionUIFigure);
            title(app.UIAxes4, 'Result')
            xlabel(app.UIAxes4, 'n')
            ylabel(app.UIAxes4, 'y[n]')
            app.ExitButton = uibutton(app.DiscreteConvolutionUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ConvolveButton = uibutton(app.DiscreteConvolutionUIFigure, 'push');
            app.ConvolveButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolveButtonPushed, true);
            app.ConvolveButton.Text = 'Convolve.';
            app.BackButton = uibutton(app.DiscreteConvolutionUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.AmplitudeSliderLabel = uilabel(app.DiscreteConvolutionUIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.DiscreteConvolutionUIFigure);
            app.AmplitudeSlider.Limits = [1 10];
            app.AmplitudeSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.MinorTicks = [];
            app.AmplitudeSlider.Value = 1;
            app.WidthOddintegersSliderLabel = uilabel(app.DiscreteConvolutionUIFigure);
            app.WidthOddintegersSliderLabel.Text = 'Width (Odd integers)';
            app.WidthOddintegersSlider = uislider(app.DiscreteConvolutionUIFigure);
            app.WidthOddintegersSlider.Limits = [10 25];
            app.WidthOddintegersSlider.MajorTicks = [10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.WidthOddintegersSlider.ValueChangedFcn = createCallbackFcn(app, @WidthOddintegersSliderValueChanged, true);
            app.WidthOddintegersSlider.MinorTicks = [];
            app.WidthOddintegersSlider.Value = 10;
            app.AmplitudeSlider_2Label = uilabel(app.DiscreteConvolutionUIFigure);
            app.AmplitudeSlider_2Label.Text = 'Amplitude';
            app.AmplitudeSlider_2 = uislider(app.DiscreteConvolutionUIFigure);
            app.AmplitudeSlider_2.Limits = [1 10];
            app.AmplitudeSlider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.AmplitudeSlider_2.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.AmplitudeSlider_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlider_2ValueChanged, true);
            app.AmplitudeSlider_2.MinorTicks = [];
            app.AmplitudeSlider_2.Value = 1;
            app.WidthOddintegersSlider_2Label = uilabel(app.DiscreteConvolutionUIFigure);
            app.WidthOddintegersSlider_2Label.Text = 'Width (Odd integers)';
            app.WidthOddintegersSlider_2 = uislider(app.DiscreteConvolutionUIFigure);
            app.WidthOddintegersSlider_2.Limits = [10 25];
            app.WidthOddintegersSlider_2.MajorTicks = [10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.WidthOddintegersSlider_2.ValueChangedFcn = createCallbackFcn(app, @WidthOddintegersSlider_2ValueChanged, true);
            app.WidthOddintegersSlider_2.MinorTicks = [];
            app.WidthOddintegersSlider_2.Value = 10;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Discrete_conv

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiscreteConvolutionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiscreteConvolutionUIFigure)
        end
    end
end