classdef FIR_Filter_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIR_Filter_Design_UIFigure      matlab.ui.Figure
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        DesiredFilterLengthLabel        matlab.ui.control.Label
        DesiredFilterLengthEditField    matlab.ui.control.NumericEditField
        DesignedFilterLengthEditFieldLabel  matlab.ui.control.Label
        DesignedFilterLengthEditField   matlab.ui.control.NumericEditField
        MagnitudeScaleButtonGroup       matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.ToggleButton
        LogarithmicButton               matlab.ui.control.ToggleButton
        MeanSquaredErrorEditFieldLabel  matlab.ui.control.Label
        MeanSquaredErrorEditField       matlab.ui.control.EditField
        Cutofffrequenciesinrangefrom0to1asafractionofF_s2Label  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        BackButton                      matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        CutoffFrequency1EditFieldLabel  matlab.ui.control.Label
        CutoffFrequency1EditField       matlab.ui.control.NumericEditField
        CutoffFrequency2EditFieldLabel  matlab.ui.control.Label
        CutoffFrequency2EditField       matlab.ui.control.NumericEditField
        EvaluateDesignButton            matlab.ui.control.Button
        MustbeanoddnumberLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        N % Designed Filter Length
        L % Desired Filter Length
        Filter % Desired Filter Frequency Response. 0 to F_s/2
        W_c1 % Cuttoff Freq 1
        W_c2 % Cuttoff Freq 1
        Filter_type % Description
    end

    methods (Access = private)
    
        function Evaluate_And_plot(app)
            app.MagnitudeScaleButtonGroup.Visible=true; %TUrn on choices when a plot is drawn.
            cla(app.UIAxes);
            app.Update_Filter;
            % We need to solve the Equation Ax=B
            % Hd is the desired filter. Column vector.
            % F is the coefficients of the DFT, x is the designed filter in frequency domain.
            N_oneside= (app.N-1)/2; % N has to be odd to use 1st order filters
            w= ( 0: app.L-1)*pi/(app.L-1);
            F=ones(app.L,N_oneside);
            % Construct the F matric
            for i = 1:N_oneside
                F(:,i+1)=(2*cos(i*w))' ;
            end
            % Solve the least squares linear equation
            Filter_Designed=F\(app.Filter');
            %Evaluate MSE by getting the Norm squared of the error and dividing by length
            app.MeanSquaredErrorEditField.Value=string(1/length(app.Filter)*...
                ((F*(Filter_Designed)-app.Filter')')*(F*(Filter_Designed)-app.Filter'));
            % we need to get the left part of the filter impulse response by the assumed symmetry.
            Filter_Designed=[fliplr(Filter_Designed(2:end)') ,Filter_Designed'];
            % Plot In the choosen scale.
            if app.MagnitudeScaleButtonGroup.SelectedObject==app.LinearButton
                plot (app.UIAxes,linspace(0,1,length(abs(freqz(Filter_Designed)))),...
                    abs(freqz(Filter_Designed)));
                hold(app.UIAxes,'on');
                plot(app.UIAxes,linspace(0,1,length(app.Filter)),app.Filter);
                app.UIAxes.YLabel.String="|H(\omega)|";
            else
                plot (app.UIAxes,linspace(0,1,length(abs(freqz(Filter_Designed)))),...
                    pow2db(abs(freqz(Filter_Designed))));
                hold(app.UIAxes,'on');
                plot(app.UIAxes,linspace(0,1,length(app.Filter)),pow2db(app.Filter));
                app.UIAxes.YLabel.String="|H(\omega)|";
            end
            legend(app.UIAxes,"Designed","Desired");
        end
        
        
        function Update_Filter(app)
             switch app.Filter_type
                case "Low Pass"
                    app.Filter=(0:app.L-1)<(app.W_c1*(app.L-1));
                case "High Pass"
                    app.Filter=(0:app.L-1)>=(app.W_c1*(app.L-1));
                case "Band Pass"
                    app.Filter=(0:app.L-1)>=(app.W_c1*(app.L-1)) & ...
                        (0:app.L-1)<(app.W_c2*(app.L-1));
                case "Band Reject"
                    app.Filter=~((0:app.L-1)>=(app.W_c1*(app.L-1)) & ...
                        (0:app.L-1)<(app.W_c2*(app.L-1)));
             end
             app.Filter=double(app.Filter);% convert from logical to double arrays.
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CutoffFrequency2EditField.Visible=false; % Hide w_c2 at first 
            app.MagnitudeScaleButtonGroup.Visible=false; %Hide until a plot is available.
            app.CutoffFrequency2EditFieldLabel.Visible=false;
            app.Filter_type=app.FilterTypeDropDown.Value;
            app.W_c1=app.CutoffFrequency1EditField.Value;
            app.W_c2=app.CutoffFrequency2EditField.Value;
            app.L=app.DesiredFilterLengthEditField.Value;
            app.N=app.DesignedFilterLengthEditField.Value;
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            if value == "Band Reject" || value == "Band Pass"
                app.CutoffFrequency2EditField.Visible=true;
                app.CutoffFrequency2EditFieldLabel.Visible=true;
            else
                app.CutoffFrequency2EditField.Visible=false;
                app.CutoffFrequency2EditFieldLabel.Visible=false;
            end
            app.Filter_type=value;
        end

        % Value changed function: CutoffFrequency2EditField
        function CutoffFrequency2EditFieldValueChanged(app, event)
            value = app.CutoffFrequency2EditField.Value;
            if value< app.CutoffFrequency1EditField.Value
                message= sprintf('Cutoff Frequency 2 has to be greater than Cutoff Frequency 1');
                uialert(app.FIR_Filter_Design_UIFigure,message,"Wrong Range");
                app.CutoffFrequency2EditField.Value=1; % reset values.
                app.W_c2=1;
            else 
                app.W_c2=value;
            end

        end

        % Value changed function: CutoffFrequency1EditField
        function CutoffFrequency1EditFieldValueChanged(app, event)
            app.W_c1=app.CutoffFrequency1EditField.Value;
        end

        % Button pushed function: EvaluateDesignButton
        function EvaluateDesignButtonPushed(app, event)
            app.Evaluate_And_plot;
        end

        % Value changed function: DesiredFilterLengthEditField
        function DesiredFilterLengthEditFieldValueChanged(app, event)
            value = app.DesiredFilterLengthEditField.Value;
            app.L=value;
            app.DesignedFilterLengthEditField.Limits(2)=value;
        end

        % Value changed function: DesignedFilterLengthEditField
        function DesignedFilterLengthEditFieldValueChanged(app, event)
            value = app.DesignedFilterLengthEditField.Value;
            if rem(value,2)==0
                app.DesignedFilterLengthEditField.Value=value-1;
            end
            app.N=app.DesignedFilterLengthEditField.Value;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Main_Menu
            closereq
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIR_Filter_Design_UIFigure = uifigure;
            app.FIR_Filter_Design_UIFigure.Name = 'FIR Filter Design';
            app.FilterTypeDropDownLabel = uilabel(app.FIR_Filter_Design_UIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FIR_Filter_Design_UIFigure);
            app.FilterTypeDropDown.Items = {'Low Pass', 'High Pass', 'Band Pass', 'Band Reject'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'Low Pass';
            app.DesiredFilterLengthLabel = uilabel(app.FIR_Filter_Design_UIFigure);
            app.DesiredFilterLengthLabel.Text = 'Desired Filter Length:';
            app.DesiredFilterLengthEditField = uieditfield(app.FIR_Filter_Design_UIFigure, 'numeric');
            app.DesiredFilterLengthEditField.Limits = [5 8192];
            app.DesiredFilterLengthEditField.ValueDisplayFormat = '%.0f';
            app.DesiredFilterLengthEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredFilterLengthEditFieldValueChanged, true);
            app.DesiredFilterLengthEditField.Value = 20;
            app.DesignedFilterLengthEditFieldLabel = uilabel(app.FIR_Filter_Design_UIFigure);
            app.DesignedFilterLengthEditFieldLabel.Text = 'Designed Filter Length';
            app.DesignedFilterLengthEditField = uieditfield(app.FIR_Filter_Design_UIFigure, 'numeric');
            app.DesignedFilterLengthEditField.Limits = [3 19];
            app.DesignedFilterLengthEditField.ValueDisplayFormat = '%.0f';
            app.DesignedFilterLengthEditField.ValueChangedFcn = createCallbackFcn(app, @DesignedFilterLengthEditFieldValueChanged, true);
            app.DesignedFilterLengthEditField.Value = 15;
            app.MagnitudeScaleButtonGroup = uibuttongroup(app.FIR_Filter_Design_UIFigure);
            app.MagnitudeScaleButtonGroup.Title = 'Magnitude Scale';
            app.LinearButton = uitogglebutton(app.MagnitudeScaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogarithmicButton = uitogglebutton(app.MagnitudeScaleButtonGroup);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.MeanSquaredErrorEditFieldLabel = uilabel(app.FIR_Filter_Design_UIFigure);
            app.MeanSquaredErrorEditFieldLabel.Enable = 'off';
            app.MeanSquaredErrorEditFieldLabel.Text = 'Mean Squared Error:';
            app.MeanSquaredErrorEditField = uieditfield(app.FIR_Filter_Design_UIFigure, 'text');
            app.MeanSquaredErrorEditField.Enable = 'off';
            app.Cutofffrequenciesinrangefrom0to1asafractionofF_s2Label = uilabel(app.FIR_Filter_Design_UIFigure);
            app.Cutofffrequenciesinrangefrom0to1asafractionofF_s2Label.Text = 'Cutoff frequencies in range from 0 to 1 as a fraction of F_s/2';
            app.UIAxes = uiaxes(app.FIR_Filter_Design_UIFigure);
            title(app.UIAxes, 'Filter Frequency Response')
            xlabel(app.UIAxes, '\omega for unit frequency rad x\pi')
            ylabel(app.UIAxes, '|H(\omega)|')
            app.BackButton = uibutton(app.FIR_Filter_Design_UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ExitButton = uibutton(app.FIR_Filter_Design_UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.CutoffFrequency1EditFieldLabel = uilabel(app.FIR_Filter_Design_UIFigure);
            app.CutoffFrequency1EditFieldLabel.Text = 'Cutoff Frequency 1';
            app.CutoffFrequency1EditField = uieditfield(app.FIR_Filter_Design_UIFigure, 'numeric');
            app.CutoffFrequency1EditField.Limits = [0 1];
            app.CutoffFrequency1EditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequency1EditFieldValueChanged, true);
            app.CutoffFrequency1EditField.Value = 0.25;
            app.CutoffFrequency2EditFieldLabel = uilabel(app.FIR_Filter_Design_UIFigure);
            app.CutoffFrequency2EditFieldLabel.Text = 'Cutoff Frequency 2';
            app.CutoffFrequency2EditField = uieditfield(app.FIR_Filter_Design_UIFigure, 'numeric');
            app.CutoffFrequency2EditField.Limits = [0 1];
            app.CutoffFrequency2EditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequency2EditFieldValueChanged, true);
            app.CutoffFrequency2EditField.Value = 1;
            app.EvaluateDesignButton = uibutton(app.FIR_Filter_Design_UIFigure, 'push');
            app.EvaluateDesignButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateDesignButtonPushed, true);
            app.EvaluateDesignButton.Text = 'Evaluate Design';
            app.MustbeanoddnumberLabel = uilabel(app.FIR_Filter_Design_UIFigure);
            app.MustbeanoddnumberLabel.Text = '* Must be an odd number.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FIR_Filter_Design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIR_Filter_Design_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIR_Filter_Design_UIFigure)
        end
    end
end