classdef Main_Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainMenuUIFigure               matlab.ui.Figure
        WelcometotheDSPsimulaterLabel  matlab.ui.control.Label
        PleaseselectoptionDropDownLabel  matlab.ui.control.Label
        PleaseselectoptionDropDown     matlab.ui.control.DropDown
        OKButton                       matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.PleaseselectoptionDropDown.Value=="Discrete Convolution"
                Discrete_conv
                closereq
            elseif app.PleaseselectoptionDropDown.Value=="FFT Analyzer"
                Spectral_Analyzer
                closereq
            else
                FIR_Filter_Design;
                closereq;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainMenuUIFigure = uifigure;
            app.MainMenuUIFigure.Name = 'Main Menu';
            app.WelcometotheDSPsimulaterLabel = uilabel(app.MainMenuUIFigure);
            app.WelcometotheDSPsimulaterLabel.Text = 'Welcome to the DSP simulater';
            app.PleaseselectoptionDropDownLabel = uilabel(app.MainMenuUIFigure);
            app.PleaseselectoptionDropDownLabel.Text = 'Please select option';
            app.PleaseselectoptionDropDown = uidropdown(app.MainMenuUIFigure);
            app.PleaseselectoptionDropDown.Items = {'Discrete Convolution', 'FFT Analyzer', 'FIR Filter Design'};
            app.PleaseselectoptionDropDown.Value = 'Discrete Convolution';
            app.OKButton = uibutton(app.MainMenuUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ExitButton = uibutton(app.MainMenuUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main_Menu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainMenuUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainMenuUIFigure)
        end
    end
end