classdef Spectral_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Spectral_AnalyzerUIFigure      matlab.ui.Figure
        AudioFileEditFieldLabel        matlab.ui.control.Label
        AudioFileEditField             matlab.ui.control.EditField
        InputMethodDropDownLabel       matlab.ui.control.Label
        InputMethodDropDown            matlab.ui.control.DropDown
        InputFunctionDropDownLabel     matlab.ui.control.Label
        InputFunctionDropDown          matlab.ui.control.DropDown
        FrequencySliderLabel           matlab.ui.control.Label
        FrequencySlider                matlab.ui.control.Slider
        TabGroup                       matlab.ui.container.TabGroup
        AnalyzerTab                    matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        ButtonGroup                    matlab.ui.container.ButtonGroup
        MagnitudeButton                matlab.ui.control.RadioButton
        PhaseButton                    matlab.ui.control.RadioButton
        RealButton                     matlab.ui.control.RadioButton
        ImaginaryButton                matlab.ui.control.RadioButton
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        LinearButton                   matlab.ui.control.RadioButton
        LogarithmicButton              matlab.ui.control.RadioButton
        LengthSliderLabel              matlab.ui.control.Label
        LengthSlider                   matlab.ui.control.Slider
        StartSliderLabel               matlab.ui.control.Label
        StartSlider                    matlab.ui.control.Slider
        LengthFineTuneSpinnerLabel     matlab.ui.control.Label
        LengthFineTuneSpinner          matlab.ui.control.Spinner
        StartFineTuneSpinnerLabel      matlab.ui.control.Label
        StartFineTuneSpinner           matlab.ui.control.Spinner
        NDFTKnobLabel                  matlab.ui.control.Label
        NDFTKnob                       matlab.ui.control.DiscreteKnob
        WindowChoiceDropDownLabel      matlab.ui.control.Label
        WindowChoiceDropDown           matlab.ui.control.DropDown
        RBWEditFieldLabel              matlab.ui.control.Label
        RBWEditField                   matlab.ui.control.NumericEditField
        FrequencystartSliderLabel      matlab.ui.control.Label
        FrequencystartSlider           matlab.ui.control.Slider
        FrequencyendSliderLabel        matlab.ui.control.Label
        FrequencyendSlider             matlab.ui.control.Slider
        CalculationModeTab             matlab.ui.container.Tab
        UIAxes2_2                      matlab.ui.control.UIAxes
        DCBiasEditFieldLabel           matlab.ui.control.Label
        DCBiasEditField                matlab.ui.control.EditField
        FrequencystartSlider_2Label    matlab.ui.control.Label
        FrequencystartSlider_2         matlab.ui.control.Slider
        FrequencyendSlider_2Label      matlab.ui.control.Label
        FrequencyendSlider_2           matlab.ui.control.Slider
        CalculatePowerinIndicatedregionButton  matlab.ui.control.Button
        PowerEditFieldLabel            matlab.ui.control.Label
        PowerEditField                 matlab.ui.control.NumericEditField
        PeakatFrequencyEditFieldLabel  matlab.ui.control.Label
        PeakatFrequencyEditField       matlab.ui.control.NumericEditField
        PeakMagnitudeEditFieldLabel    matlab.ui.control.Label
        PeakMagnitudeEditField         matlab.ui.control.NumericEditField
        OnlyavailableforAudioFilesLabel  matlab.ui.control.Label
        PerformRMSAveragingButton      matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        BackButton                     matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        ComparisonModeButton           matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        Input_Signal % the audio file loaded.
        F_s % its sampling frequency.
    end

    methods (Access = private)
    
        function Plot_All(app)
            if app.F_s~=-1 %To avoid entering the function with no Input signal
               app.RBWEditField.Value=app.F_s/str2double(app.NDFTKnob.Value); %Calculate the RBW
               window = app.Get_Window'; % Get the window
               start= app.StartFineTuneSpinner.Value; %Get Window start time
               %% For Example functions the functions will be generated to have only the length of the window
               if app.InputMethodDropDown.Value=="Example functions"
                   t_axis=(0:length(window)-1)/app.F_s; % Time axis same as window width.
                   if app.InputFunctionDropDown.Value=="Sinc"
                       %The MATLAB function sinc calculate sin(pi*t)/pi*t
                       %However we want to Plot sin(W*t)/pi*t to achieve a square wave of width 2W in freq domain.
                       app.Input_Signal=sinc(2*ceil(app.FrequencySlider.Value)*t_axis)*2*ceil(app.FrequencySlider.Value); 
                       signal= window.*app.Input_Signal(start+1:start+length(window)); %Mult by window
                   elseif app.InputFunctionDropDown.Value=="Cosine wave"
                       app.Input_Signal=...
                           cos(2*pi*ceil(app.FrequencySlider.Value)*t_axis); % Cosine(2 pi Freq t )
                       signal= window.*app.Input_Signal(start+1:start+length(window));%Mult by window
                   end
               else
                   %Here we use the signal loaded from audio file
                   signal= window.*app.Input_Signal(start+1:start+length(window));  
                   %Here the start of the window is enabled.
                   t_axis=(start:start+length(window)-1)/app.F_s;
               end
               %Time signal is plotted.
               plot(app.UIAxes,t_axis,signal)
               %Zero pad to the N DFT points then apply fft.
               freq_signal=fft_rec(app,[signal zeros(1,str2double(app.NDFTKnob.Value)-length(window))]);
               %Frequency limits from the sliders.
               startf=app.FrequencystartSlider.Value;
               endf=app.FrequencyendSlider.Value;
               % f=m*F_s/N
               f_axis=(0:length(freq_signal)-1)*app.F_s/str2double(app.NDFTKnob.Value);
               % Get the Peak value.
               app.PeakMagnitudeEditField.Value=max(abs(freq_signal));
               max_indices=find(abs(freq_signal)==app.PeakMagnitudeEditField.Value)*app.F_s/str2double(app.NDFTKnob.Value);
               app.PeakatFrequencyEditField.Value=max_indices(1); % Maximum peaks can occur twice due to symmetry
               if abs(freq_signal(1))~=0
                   %%According to Lyons in section 3.4 A_0=X(0)/N
                   % Get the DC Bias from the magnitude at freq=0
                   app.DCBiasEditField.Value=string(abs(freq_signal(1))/str2double(app.NDFTKnob.Value));
               else 
                   app.DCBiasEditField.Value="NO DC bias";
               end
               %Plot according to the plot options.
               if app.ButtonGroup.SelectedObject==app.MagnitudeButton
                   freq_signal=abs(freq_signal);
                   if app.ButtonGroup_2.SelectedObject==app.LinearButton
                       app.UIAxes2.YLabel.String='|x(f)|';
                       plot(app.UIAxes2,f_axis,freq_signal)
                   else
                       freq_signal=pow2db(freq_signal);
                       app.UIAxes2.YLabel.String='|x(f)| dB';
                       plot(app.UIAxes2,f_axis,freq_signal)
                   end
               elseif app.ButtonGroup.SelectedObject==app.PhaseButton
                   freq_signal=angle(freq_signal);
                   app.UIAxes2.YLabel.String='<x(f) rad';
                   plot(app.UIAxes2,f_axis,freq_signal)
               elseif app.ButtonGroup.SelectedObject==app.RealButton
                   freq_signal=real(freq_signal);
                   app.UIAxes2.YLabel.String='Re{x(f)}';
                   plot(app.UIAxes2,f_axis,freq_signal)
               elseif app.ButtonGroup.SelectedObject==app.ImaginaryButton
                   freq_signal=imag(freq_signal);
                   app.UIAxes2.YLabel.String='Re{x(f)}';
                   plot(app.UIAxes2,f_axis,freq_signal)
               end
               %Set the plot limits according the frequency limits provided by user.
               axis(app.UIAxes2,[startf, endf+0.00001,min(freq_signal),max(freq_signal)]);
            end
            
        end
        
        function w = Get_Window(app)
            switch app.WindowChoiceDropDown.Value
                case 'Rectangular'
                    w=rectwin(app.LengthFineTuneSpinner.Value);
                case 'Triangular'
                    w=triang(app.LengthFineTuneSpinner.Value);
                case 'Hamming'
                    w=hamming(app.LengthFineTuneSpinner.Value,'periodic');
                case 'Hanning'
                    w=hann(app.LengthFineTuneSpinner.Value,'periodic');
            end
            return
        end
        
        function res = fft_rec(app,input_signal)
            N=length(input_signal);
            if N==1
                res=input_signal;
                return;
            end
            twiddles=exp(-2i*pi*(0:N-1)./N);
            first_half=fft_rec(app,input_signal(1:2:N));
            second_half =fft_rec(app,input_signal(2:2:N));
            res = [ first_half+ twiddles(1:N/2).*second_half , first_half+ twiddles(N/2+1:N).*second_half];
        end
        
        function RMSAverag_Plot(app)
            if app.InputMethodDropDown.Value=="Audio File"
                %Initialize values:
                NumOfSamplesAveraged=0; 
                Averaged=zeros(1,str2double(app.NDFTKnob.Value));
                %Iterate and accumulate the absolute squared fft.
                for i = 1:str2double(app.NDFTKnob.Value)/2:length(app.Input_Signal)
                    if (i+str2double(app.NDFTKnob.Value)-1)>length(app.Input_Signal) %for last iteration mayhave less numbers than N
                        NumOfSamplesAveraged=NumOfSamplesAveraged+1;
                        Averaged= Averaged  + abs(fft_rec(app,[app.Input_Signal(i:length(app.Input_Signal))...
                            ,zeros(1,str2double(app.NDFTKnob.Value)-(length(app.Input_Signal)-i+1))])).^2;
                    else
                        %Add the absolute squared of the fft of the current set on input
                        Averaged= Averaged  + abs(fft_rec(app,app.Input_Signal(i:i+str2double(app.NDFTKnob.Value)-1))).^2;
                        NumOfSamplesAveraged=NumOfSamplesAveraged+1; %Increment the number of samples to average on.
                    end
                end
                RMSAveraged= (Averaged./NumOfSamplesAveraged).^(1/2); % Take the root of the mean squared
                freq_signal=RMSAveraged; % The averaged signal is the frequency signal
                %%Specify the f_axis and plot.
                f_axis=(0:length(freq_signal)-1)*app.F_s/str2double(app.NDFTKnob.Value);
                app.UIAxes2_2.YLabel.String='|x(f)| dB ';
                plot(app.UIAxes2_2,f_axis,pow2db(abs(freq_signal))) % Plot on the logarithmic scale.
            else 
                cla(app.UIAxes2_2); % Clear the Graph.
            end
        end
        
end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %%Start in FUll Screen Mode
           drawnow
           app.Spectral_AnalyzerUIFigure.WindowState='maximized';
           %%Adjust Limits and turn off values unless their use is due.
           app.LengthFineTuneSpinner.Value=str2double(app.NDFTKnob.Value);
           app.LengthFineTuneSpinner.Limits=[10,app.LengthFineTuneSpinner.Value];
           app.LengthSlider.Limits=app.LengthFineTuneSpinner.Limits;
           app.LengthSlider.Value=app.LengthFineTuneSpinner.Value;
           app.StartFineTuneSpinner.Enable=false;
           app.StartSlider.Enable=false;
           app.WindowChoiceDropDown.Enable=false;
           app.NDFTKnob.Enable=false;
           app.LengthSlider.Enable=false;
           app.LengthFineTuneSpinner.Enable=false;
           app.ButtonGroup.Visible=false;
           app.ButtonGroup_2.Visible= false;
           app.FrequencySlider.Enable=false;
           app.InputFunctionDropDown.Enable=false;
           app.FrequencystartSlider.Enable=false;
           app.FrequencyendSlider.Enable=false;
           app.FrequencystartSlider_2.Enable=false;
           app.FrequencyendSlider_2.Enable=false;
           app.PerformRMSAveragingButton.Enable=false;
           app.CalculatePowerinIndicatedregionButton.Enable=false;
           app.F_s=-1; %Initialize and indicate that there is no signal loaded.

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path]= uigetfile({'*.wav;*.mp3;*.flac;*.ogg'},...
                'Select an Audio File');
            app.Spectral_AnalyzerUIFigure.Visible = 'off';
            app.Spectral_AnalyzerUIFigure.Visible = 'on';
            if file ~=0
                 app.AudioFileEditField.Value = file;
                  try
                     %%Get input signal
                     [app.Input_Signal , app.F_s]=audioread(strcat(path,file));
                     app.Input_Signal=app.Input_Signal';
                     %%Set the window start limits.
                     app.StartFineTuneSpinner.Limits=[0,...
                         length(app.Input_Signal)-app.LengthFineTuneSpinner.Value-1];
                     app.StartSlider.Limits=app.StartFineTuneSpinner.Limits;
                     %%Enable the object that operated on audio files
                     app.StartFineTuneSpinner.Enable=true;
                     app.StartSlider.Enable=true;
                     app.WindowChoiceDropDown.Enable=true;
                     app.NDFTKnob.Enable=true;
                     app.LengthSlider.Enable=true;
                     app.LengthFineTuneSpinner.Enable=true;                   
                     app.ButtonGroup.Visible=true;
                     app.ButtonGroup_2.Visible=true;
                     app.FrequencystartSlider.Enable=true;
                     app.FrequencyendSlider.Enable=true;
                     app.FrequencystartSlider_2.Enable=true;
                     app.FrequencyendSlider_2.Enable=true;
                     app.PerformRMSAveragingButton.Enable=true;
                    app.CalculatePowerinIndicatedregionButton.Enable=true;
                      %Adjust Frequency end and start limits 
                    app.FrequencystartSlider.Limits=[0,floor(0.9*app.F_s)];% End has to be bigger than start 
                    app.FrequencyendSlider.Limits=[0,app.F_s];
                    app.FrequencyendSlider.Value=app.F_s;
                    app.FrequencystartSlider.Value=0;
                    app.FrequencystartSlider_2.Limits=[0,floor(0.9*app.F_s/2)];% End has to be bigger than start 
                    app.FrequencyendSlider_2.Limits=[0,app.F_s/2-1]; %% less than F_s/2 because that is the unique range for freq.s
                    app.FrequencyendSlider_2.Value=app.F_s/2-1;
                    app.FrequencystartSlider_2.Value=0;
                    app.Plot_All;
                    
                     
                 catch exp
                     %% Senf errors and warnings to user
                     switch exp.identifier
                         case 'MATLAB:audiovideo:audioread:FileTypeNotSupported'
                             message= sprintf('File type not supported. \n Please Enter a valid audio file!');
                             uialert(app.Spectral_AnalyzerUIFigure,...
                            message,...
                            "INVALID file!");
                         case 'MATLAB:nomem'
                             message= sprintf('Out of Memory \n Please enter a smaller file');
                              uialert(app.Spectral_AnalyzerUIFigure,...
                            message,"No Memory");
                     end
                 end  
            else
                app.AudioFileEditField.Value = "";
                uialert(app.Spectral_AnalyzerUIFigure,...
                    "Please Select a file!",'No file selected',...
                    'Icon','Warning');
            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Main_Menu 
            closereq
        end

        % Value changing function: LengthSlider
        function LengthSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LengthFineTuneSpinner.Value=round(changingValue); % has to be an integer.
            app.Plot_All;            
        end

        % Value changing function: LengthFineTuneSpinner
        function LengthFineTuneSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.LengthSlider.Value=changingValue; % Simultaneos change
            app.Plot_All;
        end

        % Value changing function: StartSlider
        function StartSliderValueChanging(app, event)
           changingValue = event.Value;
            app.StartFineTuneSpinner.Value=changingValue; % Simultaneos change
            app.Plot_All;
        end

        % Value changing function: StartFineTuneSpinner
        function StartFineTuneSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.StartSlider.Value=changingValue; % Simultaneos change
            app.Plot_All;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %% Only enable Linear/Logarithmic scale in case of plotting magnitude
            if app.ButtonGroup.SelectedObject~=app.MagnitudeButton
                app.ButtonGroup_2.Visible=false;
            else
                app.ButtonGroup_2.Visible=true;
            end
            app.Plot_All;
        end

        % Value changed function: NDFTKnob
        function NDFTKnobValueChanged(app, event)
           %%In example functions the window length is already less than the smallest N
            if app.InputMethodDropDown.Value== "Audio File"
                app.LengthFineTuneSpinner.Limits=[10,str2double(app.NDFTKnob.Value)];
                app.LengthFineTuneSpinner.Value=str2double(app.NDFTKnob.Value); % swapped these to avoid errors.
                app.LengthSlider.Limits=app.LengthFineTuneSpinner.Limits;
                app.LengthSlider.Value=app.LengthFineTuneSpinner.Value;
                app.StartFineTuneSpinner.Limits=[0,...
                             length(app.Input_Signal)-app.LengthFineTuneSpinner.Value-1];
                app.StartSlider.Limits=app.StartFineTuneSpinner.Limits;
            end
            app.Plot_All;
        end

        % Value changed function: WindowChoiceDropDown
        function WindowChoiceDropDownValueChanged(app, event)
                        app.Plot_All;  %% Update plots.
        end

        % Button pushed function: ComparisonModeButton
        function ComparisonModeButtonPushed(app, event)
            Comparison_Mode 
            closereq
        end

        % Value changed function: InputMethodDropDown
        function InputMethodDropDownValueChanged(app, event)
            value = app.InputMethodDropDown.Value;
            %mainly the limits are modified some objects get enabled or disabled.
            if value == "Audio File"
                app.LengthFineTuneSpinner.Limits=[10,str2double(app.NDFTKnob.Value)];
                app.LengthFineTuneSpinner.Value=str2double(app.NDFTKnob.Value); % swapped these to avoid errors
                app.LengthSlider.Limits=app.LengthFineTuneSpinner.Limits;
                app.LengthSlider.Value=app.LengthFineTuneSpinner.Value;
                app.StartFineTuneSpinner.Enable=false;
                app.StartSlider.Enable=false;
                app.WindowChoiceDropDown.Enable=false;
                app.NDFTKnob.Enable=false;
                app.LengthSlider.Enable=false;
                app.LengthFineTuneSpinner.Enable=false;
                app.ButtonGroup.Visible=false;
                app.ButtonGroup_2.Visible= false;
                app.F_s=-1; %Initialize and indicate that there is no signal loaded.
                app.FrequencySlider.Enable=false;
                app.InputFunctionDropDown.Enable=false;
                app.FrequencystartSlider.Enable=false;
                app.FrequencyendSlider.Enable=false;
                app.AudioFileEditField.Enable=true;
                app.BrowseButton.Enable=true;
                app.StartFineTuneSpinner.Visible=true;
                app.StartSlider.Visible=true;
                app.StartFineTuneSpinnerLabel.Visible=true;
                app.StartSliderLabel.Visible=true;
                app.ButtonGroup.SelectedObject=app.MagnitudeButton;
            elseif value == "Example functions"
                app.AudioFileEditField.Value="";
                app.FrequencySlider.Enable=true;
                app.InputFunctionDropDown.Enable=true;
                app.AudioFileEditField.Enable=false;
                app.BrowseButton.Enable=false;
                app.StartFineTuneSpinner.Visible=false;
                app.StartSlider.Visible=false;
                app.StartFineTuneSpinnerLabel.Visible=false;
                app.StartSliderLabel.Visible=false;
                app.StartFineTuneSpinner.Value=0;
                app.StartSlider.Value=0;
                app.WindowChoiceDropDown.Enable=true;
                app.NDFTKnob.Enable=true;
                app.LengthSlider.Enable=true;
                app.LengthFineTuneSpinner.Enable=true;                   
                app.ButtonGroup.Visible=true;
                app.ButtonGroup_2.Visible=true;
                app.ButtonGroup.SelectedObject=app.MagnitudeButton;
                app.F_s=400; % Set the Samping Frequency to more thab double the maximum Nyquist rate.
                app.LengthFineTuneSpinner.Value=100; % to make the signal visible
                app.LengthFineTuneSpinner.Limits=[10,app.LengthFineTuneSpinner.Value];
                app.LengthSlider.Limits=app.LengthFineTuneSpinner.Limits;
                app.LengthSlider.Value=app.LengthFineTuneSpinner.Value;
                app.FrequencystartSlider.Limits=[0,floor(0.9*app.F_s)];
                app.FrequencyendSlider.Limits=[0,app.F_s];
                app.FrequencyendSlider.Value=app.F_s;
                app.FrequencystartSlider_2.Limits=[0,floor(0.9*app.F_s/2)];
                app.FrequencyendSlider_2.Limits=[0,app.F_s/2];
                app.FrequencyendSlider_2.Value=app.F_s/2;
                app.FrequencystartSlider.Enable=true;
                app.FrequencyendSlider.Enable=true;
                app.FrequencystartSlider_2.Enable=true;
                app.FrequencyendSlider_2.Enable=true;
                app.PerformRMSAveragingButton.Enable=false;
                app.CalculatePowerinIndicatedregionButton.Enable=true;
                app.Plot_All; % replot everything.
            end
        end

        % Value changed function: LengthFineTuneSpinner
        function LengthFineTuneSpinnerValueChanged(app, event)
            value = app.LengthFineTuneSpinner.Value;
            app.LengthSlider.Value=value;
            app.Plot_All;
        end

        % Value changed function: InputFunctionDropDown
        function InputFunctionDropDownValueChanged(app, event)
            app.Plot_All;
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            app.Plot_All;
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            app.Plot_All;
        end

        % Value changing function: FrequencyendSlider
        function FrequencyendSliderValueChanging(app, event)
            app.Plot_All;
            
        end

        % Value changed function: FrequencystartSlider
        function FrequencystartSliderValueChanged(app, event)
            %%adjust such that end is bigger than start
            value = app.FrequencystartSlider.Value;
            if app.FrequencyendSlider.Value<value
                app.FrequencyendSlider.Value=app.F_s;
            end
            app.FrequencyendSlider.Limits=[value,app.F_s];
            app.Plot_All;
        end

        % Value changed function: FrequencyendSlider
        function FrequencyendSliderValueChanged(app, event)
            app.Plot_All;            
        end

        % Callback function
        function CalculationModeButtonPushed(app, event)
            Calculation_Mode
            closereq
        end

        % Value changing function: FrequencystartSlider
        function FrequencystartSliderValueChanging(app, event)
            %changingValue = event.Value;
            
        end

        % Button pushed function: PerformRMSAveragingButton
        function PerformRMSAveragingButtonPushed(app, event)
            app.RMSAverag_Plot;
        end

        % Value changed function: FrequencystartSlider_2
        function FrequencystartSlider_2ValueChanged(app, event)
            %%adjust such that end is bigger than start
            value = app.FrequencystartSlider_2.Value;
            if app.FrequencyendSlider_2.Value<value
                app.FrequencyendSlider_2.Value=app.F_s/2;
            end
            app.FrequencyendSlider_2.Limits=[value,app.F_s/2];
        end

        % Button pushed function: 
        % CalculatePowerinIndicatedregionButton
        function CalculatePowerinIndicatedregionButtonPushed(app, event)
            app.PowerEditField.Value=bandpower(app.Input_Signal,app.F_s,[app.FrequencystartSlider_2.Value,app.FrequencyendSlider_2.Value]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Spectral_AnalyzerUIFigure = uifigure;
            app.Spectral_AnalyzerUIFigure.Name = 'Spectral Analyzer';
            app.AudioFileEditFieldLabel = uilabel(app.Spectral_AnalyzerUIFigure);
            app.AudioFileEditFieldLabel.Text = 'Audio File';
            app.AudioFileEditField = uieditfield(app.Spectral_AnalyzerUIFigure, 'text');
            app.AudioFileEditField.Editable = 'off';
            app.InputMethodDropDownLabel = uilabel(app.Spectral_AnalyzerUIFigure);
            app.InputMethodDropDownLabel.Text = 'Input Method';
            app.InputMethodDropDown = uidropdown(app.Spectral_AnalyzerUIFigure);
            app.InputMethodDropDown.Items = {'Audio File', 'Example functions'};
            app.InputMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @InputMethodDropDownValueChanged, true);
            app.InputMethodDropDown.Value = 'Audio File';
            app.InputFunctionDropDownLabel = uilabel(app.Spectral_AnalyzerUIFigure);
            app.InputFunctionDropDownLabel.Text = 'Input Function';
            app.InputFunctionDropDown = uidropdown(app.Spectral_AnalyzerUIFigure);
            app.InputFunctionDropDown.Items = {'Sinc', 'Cosine wave'};
            app.InputFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @InputFunctionDropDownValueChanged, true);
            app.InputFunctionDropDown.Value = 'Sinc';
            app.FrequencySliderLabel = uilabel(app.Spectral_AnalyzerUIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.Spectral_AnalyzerUIFigure);
            app.FrequencySlider.Limits = [10 100];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.Value = 10;
            app.TabGroup = uitabgroup(app.Spectral_AnalyzerUIFigure);
            app.AnalyzerTab = uitab(app.TabGroup);
            app.AnalyzerTab.Title = 'Analyzer';
            app.UIAxes = uiaxes(app.AnalyzerTab);
            title(app.UIAxes, 'Time-Domain Signal')
            xlabel(app.UIAxes, 't s')
            ylabel(app.UIAxes, 'x(t)')
            app.UIAxes2 = uiaxes(app.AnalyzerTab);
            title(app.UIAxes2, 'Frequency-Domain Signal')
            xlabel(app.UIAxes2, 'f Hz')
            ylabel(app.UIAxes2, '|x(f)|')
            app.ButtonGroup = uibuttongroup(app.AnalyzerTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MagnitudeButton = uiradiobutton(app.ButtonGroup);
            app.MagnitudeButton.Text = 'Magnitude';
            app.MagnitudeButton.Value = true;
            app.PhaseButton = uiradiobutton(app.ButtonGroup);
            app.PhaseButton.Text = 'Phase';
            app.RealButton = uiradiobutton(app.ButtonGroup);
            app.RealButton.Text = 'Real ';
            app.ImaginaryButton = uiradiobutton(app.ButtonGroup);
            app.ImaginaryButton.Text = 'Imaginary';
            app.ButtonGroup_2 = uibuttongroup(app.AnalyzerTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.LinearButton = uiradiobutton(app.ButtonGroup_2);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogarithmicButton = uiradiobutton(app.ButtonGroup_2);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.LengthSliderLabel = uilabel(app.AnalyzerTab);
            app.LengthSliderLabel.Text = 'Length';
            app.LengthSlider = uislider(app.AnalyzerTab);
            app.LengthSlider.ValueChangingFcn = createCallbackFcn(app, @LengthSliderValueChanging, true);
            app.StartSliderLabel = uilabel(app.AnalyzerTab);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.AnalyzerTab);
            app.StartSlider.ValueChangingFcn = createCallbackFcn(app, @StartSliderValueChanging, true);
            app.LengthFineTuneSpinnerLabel = uilabel(app.AnalyzerTab);
            app.LengthFineTuneSpinnerLabel.Text = 'Length Fine Tune';
            app.LengthFineTuneSpinner = uispinner(app.AnalyzerTab);
            app.LengthFineTuneSpinner.ValueChangingFcn = createCallbackFcn(app, @LengthFineTuneSpinnerValueChanging, true);
            app.LengthFineTuneSpinner.ValueChangedFcn = createCallbackFcn(app, @LengthFineTuneSpinnerValueChanged, true);
            app.StartFineTuneSpinnerLabel = uilabel(app.AnalyzerTab);
            app.StartFineTuneSpinnerLabel.Text = 'Start Fine Tune';
            app.StartFineTuneSpinner = uispinner(app.AnalyzerTab);
            app.StartFineTuneSpinner.ValueChangingFcn = createCallbackFcn(app, @StartFineTuneSpinnerValueChanging, true);
            app.NDFTKnobLabel = uilabel(app.AnalyzerTab);
            app.NDFTKnobLabel.Text = 'N-DFT';
            app.NDFTKnob = uiknob(app.AnalyzerTab, 'discrete');
            app.NDFTKnob.Items = {'8192', '16384', '32768', '65536'};
            app.NDFTKnob.ValueChangedFcn = createCallbackFcn(app, @NDFTKnobValueChanged, true);
            app.NDFTKnob.Value = '65536';
            app.WindowChoiceDropDownLabel = uilabel(app.AnalyzerTab);
            app.WindowChoiceDropDownLabel.Text = 'Window Choice';
            app.WindowChoiceDropDown = uidropdown(app.AnalyzerTab);
            app.WindowChoiceDropDown.Items = {'Rectangular', 'Triangular', 'Hamming', 'Hanning'};
            app.WindowChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowChoiceDropDownValueChanged, true);
            app.WindowChoiceDropDown.Value = 'Rectangular';
            app.RBWEditFieldLabel = uilabel(app.AnalyzerTab);
            app.RBWEditFieldLabel.Text = 'RBW';
            app.RBWEditField = uieditfield(app.AnalyzerTab, 'numeric');
            app.RBWEditField.Editable = 'off';
            app.FrequencystartSliderLabel = uilabel(app.AnalyzerTab);
            app.FrequencystartSliderLabel.Text = 'Frequency start';
            app.FrequencystartSlider = uislider(app.AnalyzerTab);
            app.FrequencystartSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencystartSliderValueChanged, true);
            app.FrequencystartSlider.ValueChangingFcn = createCallbackFcn(app, @FrequencystartSliderValueChanging, true);
            app.FrequencyendSliderLabel = uilabel(app.AnalyzerTab);
            app.FrequencyendSliderLabel.Text = 'Frequency end';
            app.FrequencyendSlider = uislider(app.AnalyzerTab);
            app.FrequencyendSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyendSliderValueChanged, true);
            app.FrequencyendSlider.ValueChangingFcn = createCallbackFcn(app, @FrequencyendSliderValueChanging, true);
            app.CalculationModeTab = uitab(app.TabGroup);
            app.CalculationModeTab.Title = 'Calculation Mode';
            app.UIAxes2_2 = uiaxes(app.CalculationModeTab);
            title(app.UIAxes2_2, 'Frequency-Domain RMS-Averaged Signal')
            xlabel(app.UIAxes2_2, 'f Hz')
            ylabel(app.UIAxes2_2, '|x(f)|')
            app.DCBiasEditFieldLabel = uilabel(app.CalculationModeTab);
            app.DCBiasEditFieldLabel.Text = 'DC Bias';
            app.DCBiasEditField = uieditfield(app.CalculationModeTab, 'text');
            app.DCBiasEditField.Editable = 'off';
            app.FrequencystartSlider_2Label = uilabel(app.CalculationModeTab);
            app.FrequencystartSlider_2Label.Text = 'Frequency start';
            app.FrequencystartSlider_2 = uislider(app.CalculationModeTab);
            app.FrequencystartSlider_2.ValueChangedFcn = createCallbackFcn(app, @FrequencystartSlider_2ValueChanged, true);
            app.FrequencyendSlider_2Label = uilabel(app.CalculationModeTab);
            app.FrequencyendSlider_2Label.Text = 'Frequency end';
            app.FrequencyendSlider_2 = uislider(app.CalculationModeTab);
            app.CalculatePowerinIndicatedregionButton = uibutton(app.CalculationModeTab, 'push');
            app.CalculatePowerinIndicatedregionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePowerinIndicatedregionButtonPushed, true);
            app.CalculatePowerinIndicatedregionButton.Text = 'Calculate Power in Indicated region.';
            app.PowerEditFieldLabel = uilabel(app.CalculationModeTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.CalculationModeTab, 'numeric');
            app.PowerEditField.Editable = 'off';
            app.PeakatFrequencyEditFieldLabel = uilabel(app.CalculationModeTab);
            app.PeakatFrequencyEditFieldLabel.Text = 'Peak  at Frequency:';
            app.PeakatFrequencyEditField = uieditfield(app.CalculationModeTab, 'numeric');
            app.PeakatFrequencyEditField.Editable = 'off';
            app.PeakMagnitudeEditFieldLabel = uilabel(app.CalculationModeTab);
            app.PeakMagnitudeEditFieldLabel.Text = 'Peak Magnitude';
            app.PeakMagnitudeEditField = uieditfield(app.CalculationModeTab, 'numeric');
            app.PeakMagnitudeEditField.Editable = 'off';
            app.OnlyavailableforAudioFilesLabel = uilabel(app.CalculationModeTab);
            app.OnlyavailableforAudioFilesLabel.Text = 'Only available for Audio Files';
            app.PerformRMSAveragingButton = uibutton(app.CalculationModeTab, 'push');
            app.PerformRMSAveragingButton.ButtonPushedFcn = createCallbackFcn(app, @PerformRMSAveragingButtonPushed, true);
            app.PerformRMSAveragingButton.Text = 'Perform RMS Averaging.';
            app.Label = uilabel(app.CalculationModeTab);
            app.Label.Text = '*DC Bias, Peak finding, Power calculations depend on the frequency signal in Anayzer tab';
            app.BackButton = uibutton(app.Spectral_AnalyzerUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ExitButton = uibutton(app.Spectral_AnalyzerUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ComparisonModeButton = uibutton(app.Spectral_AnalyzerUIFigure, 'push');
            app.ComparisonModeButton.ButtonPushedFcn = createCallbackFcn(app, @ComparisonModeButtonPushed, true);
            app.ComparisonModeButton.Text = 'Comparison Mode';
            app.BrowseButton = uibutton(app.Spectral_AnalyzerUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Spectral_Analyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Spectral_AnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Spectral_AnalyzerUIFigure)
        end
    end
end